classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PortfolioSelectionTab           matlab.ui.container.Tab
        AddOptiontoPortfolioPanel       matlab.ui.container.Panel
        SetDateandRatesButton           matlab.ui.control.Button
        AddtoPortfolioButton            matlab.ui.control.Button
        DeletePortfolioButton           matlab.ui.control.Button
        OptionStyleButtonGroup          matlab.ui.container.ButtonGroup
        EuropeanButton                  matlab.ui.control.RadioButton
        AmericanButton                  matlab.ui.control.RadioButton
        PositionButtonGroup             matlab.ui.container.ButtonGroup
        LongButton                      matlab.ui.control.RadioButton
        ShortButton                     matlab.ui.control.RadioButton
        OptionTypeButtonGroup           matlab.ui.container.ButtonGroup
        CallButton                      matlab.ui.control.RadioButton
        PutButton                       matlab.ui.control.RadioButton
        InstrumentTypeDropDown          matlab.ui.control.DropDown
        InstrumentTypeDropDownLabel     matlab.ui.control.Label
        StrikeEditField                 matlab.ui.control.NumericEditField
        StrikeEditFieldLabel            matlab.ui.control.Label
        UnderlyingPriceEditFieldLabel   matlab.ui.control.Label
        UnderlyingPriceEditField        matlab.ui.control.NumericEditField
        VolatilityEditFieldLabel        matlab.ui.control.Label
        VolatilityEditField             matlab.ui.control.NumericEditField
        OptionMaturityDatePickerLabel   matlab.ui.control.Label
        OptionMaturityDatePicker        matlab.ui.control.DatePicker
        YieldEditField                  matlab.ui.control.NumericEditField
        YieldLabel                      matlab.ui.control.Label
        BarrierPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        BarrierTypeDropDown             matlab.ui.control.DropDown
        BarrierTypeLabel                matlab.ui.control.Label
        BarrierValueLabel               matlab.ui.control.Label
        BarrierValueEditField           matlab.ui.control.NumericEditField
        BinaryPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BinaryValueEditField            matlab.ui.control.NumericEditField
        BinaryValueEditFieldLabel       matlab.ui.control.Label
        SimulationTrialsEditFieldLabel  matlab.ui.control.Label
        SimulationTrialsEditField       matlab.ui.control.NumericEditField
        QuantitySpinner                 matlab.ui.control.Spinner
        QuantitySpinnerLabel            matlab.ui.control.Label
        UnderlyingDropDownLabel         matlab.ui.control.Label
        UnderlyingDropDown              matlab.ui.control.DropDown
        StorePortfolioButton            matlab.ui.control.Button
        PortfolioCompositionPanel       matlab.ui.container.Panel
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UITable3                        matlab.ui.control.Table
        UITable4                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        LONGLabel                       matlab.ui.control.Label
        SHORTLabel                      matlab.ui.control.Label
        TOTLabel                        matlab.ui.control.Label
        TimeSeriesAnalysisTab           matlab.ui.container.Tab
        TimeSeriesChartPanel            matlab.ui.container.Panel
        UIAxes6                         matlab.ui.control.UIAxes
        TimeSeriesChartControlPanel     matlab.ui.container.Panel
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        Series1DropDownLabel            matlab.ui.control.Label
        Series1DropDown                 matlab.ui.control.DropDown
        Series2DropDownLabel            matlab.ui.control.Label
        Series2DropDown                 matlab.ui.control.DropDown
        LogReturnsDistributionFitterPanel  matlab.ui.container.Panel
        CurrencyDropDownLabel           matlab.ui.control.Label
        CurrencyDropDown                matlab.ui.control.DropDown
        StartDateDatePicker_3Label      matlab.ui.control.Label
        StartDateDatePicker_3           matlab.ui.control.DatePicker
        OverlayDistributionCheckBox     matlab.ui.control.CheckBox
        DistributionTypeDropDownLabel   matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        TextArea                        matlab.ui.control.TextArea
        UIAxes7                         matlab.ui.control.UIAxes
        UITable5                        matlab.ui.control.Table
        CorrelationMapPanel             matlab.ui.container.Panel
        CorrelationMapandSummaryStatisticsControlPanel  matlab.ui.container.Panel
        StartDateDatePicker_2Label      matlab.ui.control.Label
        StartDateDatePicker_2           matlab.ui.control.DatePicker
        EndDateDatePicker_2Label        matlab.ui.control.Label
        EndDateDatePicker_2             matlab.ui.control.DatePicker
        BackgroundColorButton           matlab.ui.control.StateButton
        VaRCalculationandBacktestingTab  matlab.ui.container.Tab
        UITable6                        matlab.ui.control.Table
        StepladderCurrencyDropDownLabel  matlab.ui.control.Label
        StepladderCurrencyDropDown      matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        SamplingPeriodDropDownLabel     matlab.ui.control.Label
        SamplingPeriodDropDown          matlab.ui.control.DropDown
        RiskHorizonEditFieldLabel       matlab.ui.control.Label
        RiskHorizonEditField            matlab.ui.control.NumericEditField
        TextArea_2Label                 matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        UITable7                        matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        VaRandESMethodDropDownLabel     matlab.ui.control.Label
        VaRandESMethodDropDown          matlab.ui.control.DropDown
        VaRBacktestingHorizonDatePickerLabel  matlab.ui.control.Label
        VaRBacktestingHorizonDatePicker  matlab.ui.control.DatePicker
        VaRLimitMlnEditFieldLabel       matlab.ui.control.Label
        VaRLimitMlnEditField            matlab.ui.control.NumericEditField
        SamplingRollingWindowDropDownLabel  matlab.ui.control.Label
        SamplingRollingWindowDropDown   matlab.ui.control.DropDown
        StartDateDatePicker_4Label      matlab.ui.control.Label
        StartDateDatePicker_4           matlab.ui.control.DatePicker
        EndDateDatePicker_3Label        matlab.ui.control.Label
        EndDateDatePicker_3             matlab.ui.control.DatePicker
        Button2                         matlab.ui.control.Button
        UITable10                       matlab.ui.control.Table
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        DeskPerformanceTab              matlab.ui.container.Tab
        ImportOptionsDataButton         matlab.ui.control.Button
        GenerateChartButton             matlab.ui.control.Button
        UITable11                       matlab.ui.control.Table
        UITable12                       matlab.ui.control.Table
        UpdateDataButton                matlab.ui.control.Button
        USDAUDPORTFOLIOLabel            matlab.ui.control.Label
        UnderlyingDropDown_2Label       matlab.ui.control.Label
        UnderlyingDropDown_2            matlab.ui.control.DropDown
        UIAxes10                        matlab.ui.control.UIAxes
    end

    
%     Dichiarazione Properties
    
    properties (Access = public)
        RateDialog % Rate and Settle Panel 
        NoRatesSetERROR % sconosciuto
        CurrencySetError % Error when setting option base currency with empty rates data
    end
    
    properties (Access = public)
       
        Settle % Variabile temporanea per salvare la data dalla finestra dialogo
        ZeroCurveEUR
        ZeroCurveUSD
        ZeroCurveAUD
        ZeroCurveGBP
       
       
    end
    
    properties (Access = private)
        FredData
        Ret % DATA LOG RETURNS, da usare nell'heatmap
        InstSens % Passa i Dati al VaR e Stepladder
        Prices
        RetTable
        RetMatrix
        PriceMatrix % Matrice unica per i tassi di cambio
        PriceTable % Tabella unica per i tassi di cambio
        Q  % Q strumenti in AddtoPortfolio Callback
        Instr % Strumenti
        counter = 0 % contatore push AddtoPortfolio
        counter2 = 0 %Conta il numero di eliminazioni di strumenti uguali
        Pricer %Pricer
        OptionTypeCategorical
        OptionTypeCategoricalLong
        OptionTypeCategoricalShort % Crea un array di strumenti
        ShortInstrumentCounter = 0
        LongInstrumentCounter = 0
        TestInstr 
        Dates 
        Rates
        Currency
        SummaryTable
        StoreDataButtonCounter
        
        file %VOLATILITY SURFACE DATA IMPORT
        PortfolioData 
        
        LongCategories_Quantities
        ShortCategories_Quantities
    end


% DICHIARAZIONE FUNZIONI


    methods (Access = private)
        
        
% PORTFOLIO PRICING DA ASSOCIARE AL PRICING COMPLETO        
        
        function [PortPrice, InstPrice, PortSens, InstSens] = PortCreationPricing(app,Instruments,Q,Pricer)


            myport = finportfolio(Instruments,Pricer,Q);

            [PortPrice, InstPrice, PortSens, InstSens] = pricePortfolio(myport);

            
        end

% FUNZIONE PER SETTARE A 0 I GRAFICI E LE TABELLE DELLA HOME
        
        function ReSetHomePlots(app)
            app.UITable2.Data = [];
            app.UITable3.Data = [];
            app.UITable4.Data = [];
            p = pie(app.UIAxes2,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes3,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes4,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            p = pie(app.UIAxes5,categorical(cellstr('No Instruments')));
            p(1).EdgeColor = 'w';
            p(2).Color = 'w';
            colormap(app.UIAxes2,parula(0));
            colormap(app.UIAxes3,parula(0));
            colormap(app.UIAxes4,parula(0));
            colormap(app.UIAxes5,parula(0));
            
        end
        
        
        function UpdateHomePlots(app,InstPrice,InstSens,PortSens)
            
%     Creazione Font
            fontstyle = uistyle;
            fontstyle.FontWeight = 'bold';
            fontstyleAl = uistyle;
            fontstyleAl.HorizontalAlignment = 'center';
            
%     Dati pos long     

            app.UITable2.Data = InstSens(InstSens.Price > 0,:);
            HeightTable = height(app.UITable2.Data);
            c2 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c2(i,:));
                s.FontColor = c2(i,:);              
                addStyle(app.UITable2,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable2,fontstyle,'column',7:16)
            addStyle(app.UITable2,fontstyleAl,'column', 7:16)
            
            app.UITable2.ColumnName = InstSens.Properties.VariableNames;
            
            %Dati pos short
            app.UITable3.ColumnName = InstSens.Properties.VariableNames;
            app.UITable3.Data = InstSens(InstPrice < 0,:);
              HeightTable = height(app.UITable3.Data);
            c3 = parula(HeightTable);
            for i = 1:HeightTable
                s = uistyle("BackgroundColor",c3(i,:));
                s.FontColor = c3(i,:);              
                addStyle(app.UITable3,s,'cell',[i 17])  %modificare numero della colonna quando si aggiungeranno le altre
            end
            addStyle(app.UITable3,fontstyle,'column',7:16)
            addStyle(app.UITable3,fontstyleAl,'column', 7:16)
            
            %Dati globali Portafoglio
            app.UITable4.ColumnName = PortSens.Properties.VariableNames;
            app.UITable4.RowName = PortSens.Properties.RowNames;
            app.UITable4.Data = PortSens(:,[1:3 5:7]);
            
            
            TempPieShortData = -InstPrice(InstPrice <0);
            TempPieLongData = InstPrice(InstPrice > 0);
            
% PLOT VUOTI SE NON SONO PRESENTI STRUMENTI

            if isempty(TempPieLongData) == 1
                TempPieLongData = categorical("No Instruments");
            end
            
            if isempty(TempPieShortData) == 1
                TempPieShortData = categorical("No Instruments");
            end
           
% PLOT TORTE PRINCIPALI         
            PieLongValue = pie(app.UIAxes2,TempPieLongData);           
            colormap(app.UIAxes2,c2)
            
            PieShortValue = pie(app.UIAxes3,TempPieShortData);
            colormap(app.UIAxes3,c3)
            
            NumSegLong = length(PieLongValue);
            NumSegShort = length(PieShortValue);
            
            for i = 1:2:NumSegLong 
                PieLongValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegLong
                PieLongValue(k).Color = 'w';
            end
            
             for i = 1:2:NumSegShort 
                PieShortValue(i).EdgeColor = 'w';
            end
            
            for k = 2:2:NumSegShort
                PieShortValue(k).Color = 'w';
            end


%    If per creare i categorical per le torte. La torta riporta il
%    totale della categoria in rapporto al totale delle opzioni,
%    non al prezzo


% RICHIAMO FUNZIONE ANALISI DATI SULLA TIPOLOGIA DELLE OPZIONI E CREAZIONE
% VETTORI PER I PIE CHARTS
[QSHORT, QLONG, LabelsLong, LabelsShort] = OptionsTypeRegrouping(app.LongInstrumentCounter,app.ShortInstrumentCounter,app.OptionTypeCategoricalLong, ...
    app.LongCategories_Quantities, app.OptionTypeCategoricalShort, app.ShortCategories_Quantities);


%       If per evitare errori per mancanza di dati nei pie chart
            
           if app.LongInstrumentCounter > 0
              PieCatLong = pie(app.UIAxes4,rmmissing(QLONG),[],LabelsLong);
              colormap(app.UIAxes4,"autumn")
              NumSegLong = length(PieCatLong);
                for i = 1:2:NumSegLong 
                    PieCatLong(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegLong
                    PieCatLong(k).Color = 'w';
                end
              
           end


            if app.ShortInstrumentCounter > 0
                 PieCatShort = pie(app.UIAxes5,rmmissing(QSHORT),[],LabelsShort);
                 colormap(app.UIAxes5,"autumn")                 
                 NumSegShort = length(PieCatShort); 
                for i = 1:2:NumSegShort 
                    PieCatShort(i).EdgeColor = 'w';
                end
            
                for k = 2:2:NumSegShort
                    PieCatShort(k).Color = 'w';
                end
           
            end


        end
        
        
% SCARICA DATI SERIE STORICHE E CREAZIONE TABELLE CONGIUNTE PREZZI E RENDIMENTI        
        function  TimeSeriesDataImport(app)
            url = 'https://fred.stlouisfed.org/';
            c = fred(url);
            
            c.DataReturnFormat = 'table';
            c.DatetimeType = 'datetime';


% Fetch all available daily foreign exchange rates between the US dollar and the Euro using the series 'DEXUSEU'. The table d contains one row for the series. Each variable describes a piece of information about the series.

            series = {'DEXUSAL' 'DEXUSEU' 'DEXUSUK'};
            SeriesNames = {'USD/AUD' 'EUR/USD' 'USD/GBP' };
           
            d = fetch(c,series);
            
            app.FredData = d.Data;
           
            close(c)

        end
        
        
        function YahooData(app)
            
            series2 = {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'};
            symbols = {'AUDGBP=X' 'EURGBP=X' 'AUDEUR=X'};
            
            value = 0;
            for i = 1:length(symbols)
                d.Value = value + i/length(symbols);
            data = getMarketDataViaYahooNew(symbols{i},datetime("01-01-1970","Format","dd-MM-uuuu"));
            data.Var1 = data.Date;
            data.Var2 = data.Close;
            app.FredData{i+3} = data(:,{ 'Var1' 'Var2'});
            end
            
            
              for i = 1:length(app.FredData)
                data = app.FredData{i};
                app.Prices{i} = data.Var2;
                app.Ret{i} = dlog((app.Prices{i}));
            end

%     UNIONE DELLE DATE PER CREARE UNA TABELLA UNICA DI PREZZI E RENDIMENTI            
            
            UniqueDates = unique([app.FredData{1}.Var1 ; app.FredData{2}.Var1 ; app.FredData{3}.Var1 ; app.FredData{4}.Var1 ; app.FredData{5}.Var1 ; app.FredData{6}.Var1]);
            [~, in1] = intersect(UniqueDates,app.FredData{1}.Var1);
            [~, in2] = intersect(UniqueDates,app.FredData{2}.Var1);
            [~, in3] = intersect(UniqueDates,app.FredData{3}.Var1);
            [~, in4] = intersect(UniqueDates,app.FredData{4}.Var1);
            [~, in5] = intersect(UniqueDates,app.FredData{5}.Var1);
            [~, in6] = intersect(UniqueDates,app.FredData{6}.Var1);
            
            
             TempPrice_TABLE = table();
             TempPrice_TABLE.Date = UniqueDates;
             TempPrice_TABLE.Var1(in1) = app.Prices{1};
             TempPrice_TABLE.Var2(in2) = app.Prices{2};
             TempPrice_TABLE.Var3(in3) = app.Prices{3};
             TempPrice_TABLE.Var4(in4) = app.Prices{4};
             TempPrice_TABLE.Var5(in5) = app.Prices{5};
             TempPrice_TABLE.Var6(in6) = app.Prices{6};
            
             TempRet_TABLE = table();
             TempRet_TABLE.Date = UniqueDates;
             TempRet_TABLE.Var1(in1) = app.Ret{1};
             TempRet_TABLE.Var2(in2) = app.Ret{2};
             TempRet_TABLE.Var3(in3) = app.Ret{3};
             TempRet_TABLE.Var4(in4) = app.Ret{4};
             TempRet_TABLE.Var5(in5) = app.Ret{5};
             TempRet_TABLE.Var6(in6) = app.Ret{6};
    
% ELIMINAZIONE 0 E TRASFORMAZIONE IN NaN             
             TempRetMatrix = table2array(TempRet_TABLE(:,2:end));
             
               
                index = TempRetMatrix == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempRetMatrix(row(i),col(i)) = nan;
                    end
                end
                
                
                TempRet_TABLE.Date = UniqueDates;
                TempRet_TABLE(:,2:7) = array2table(TempRetMatrix);
% TRASFORMAZIONE PREZZI IN TABELLA UNICA

                TempMatrixPrice = table2array(TempPrice_TABLE(:,2:end));
             
               
                index = TempMatrixPrice == 0;
                [row,col] = find(index);
            
                if nnz(index) >0
                    for i = 1:height(row)
                        TempMatrixPrice(row(i),col(i)) = nan;
                    end
                end
                
                TempPrice_TABLE.Date = UniqueDates;
                TempPrice_TABLE(:,2:7) = array2table(TempMatrixPrice);
                
           
            app.RetTable = TempRet_TABLE;
            app.RetMatrix = TempRetMatrix;
            app.PriceMatrix = TempMatrixPrice;
            app.PriceTable = TempPrice_TABLE;


        end
            
        
        function TimeSeriesPlotUpdate(app)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait: Starting Time Series Tab','Message','Updating Chart'); 


            d.Value = 0.40;
            d.Message = 'Retrieving Data';
            
            SelectedData1 = app.FredData{app.Series1DropDown.Value};
            SelectedData2 = app.FredData{app.Series2DropDown.Value};
            Min_Date1 = min(SelectedData1{:,1});
            Min_Date2 = min(SelectedData2{:,1});
            Min_Gen = min(Min_Date2,Min_Date1);
            app.StartDateDatePicker.Limits = [Min_Gen datetime("today")-1];
            
            
            d.Value = 0.5;
            d.Message = 'Generating Charts';
            
            
            if app.Series2DropDown.Value == 0
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.15 0.15 0.15];
                yyaxis(app.UIAxes6,"left")               
            
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
                
            else if app.Series1DropDown.Value == app.Series2DropDown.Value
                    yyaxis(app.UIAxes6,"right")
                    cla(app.UIAxes6)
                    app.UIAxes6.YColor = [0.15 0.15 0.15];
                    yyaxis(app.UIAxes6,"left")
                    plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
            else
                               
                FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
                
                d.Value = 0.6;
                d.Message = 'Generating Charts';


                yyaxis(app.UIAxes6,'left')
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series1DropDown.Value+1})
                ylabel(app.UIAxes6,FXNAME{app.Series1DropDown.Value})
                
                
                d.Value = 0.85;
                d.Message = 'Generating Charts';
                
                
                yyaxis(app.UIAxes6,"right")
                cla(app.UIAxes6)
                app.UIAxes6.YColor = [0.85 0.33 .1]
                plot(app.UIAxes6,app.PriceTable.Date(app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value),app.PriceTable{app.PriceTable.Date > app.StartDateDatePicker.Value & app.PriceTable.Date < app.EndDateDatePicker.Value,app.Series2DropDown.Value+1})
                ylabel(app.UIAxes6,FXNAME{app.Series2DropDown.Value})
                
                
                end
            end
            
            
                d.Value = 1;
                d.Message = 'Charts Generated Successfully';


        end


%     HEATMAP
        
        
        function Heatmap(app)

% LIMITS SETTING            
            app.StartDateDatePicker_2.Limits = app.StartDateDatePicker.Limits;
            app.EndDateDatePicker_2.Limits = [app.StartDateDatePicker_2.Value+1 datetime("today")];

%CREAZIONE DI UNA TABELLA TEMPORANEA CONTENENTE I RENDIMENTI SELEZIONATI

            index = app.RetTable.Date > app.StartDateDatePicker_2.Value & app.RetTable.Date < app.EndDateDatePicker_2.Value;
            TempMatrix = app.RetMatrix(index,:);
            FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            
            if isempty(rmmissing(TempMatrix)) == 1
                app.TextArea.FontColor = 'r';
                app.TextArea.FontWeight = 'bold';
                app.TextArea.Value = ["CORRELATION MATRIX WARNING:"];
                pause(5)
                app.TextArea.FontWeight = 'normal';
                app.TextArea.Value = [ "Not enough data available for all currencies into the date range choosen. Please, try a bigger interval."];
                pause(5)
                app.TextArea.Value = "";
                
            else
                corrMatr = corr(rmmissing(TempMatrix));
                heatmap(app.CorrelationMapPanel,FXNAME,FXNAME,corrMatr,"ColorbarVisible","on","Colormap",autumn(8))
            
            end


        end
            
        
        function  HistTimeSeries(app)
            
            
            %GEnerazione Istogramma
                app.StartDateDatePicker_3.Limits = [min(app.RetTable.Date) max(app.RetTable.Date)];
                histData = app.RetTable{app.RetTable.Date > app.StartDateDatePicker_3.Value,app.CurrencyDropDown.Value+1}; 
            
                histogram(app.UIAxes7,histData,'FaceColor','r','EdgeColor','w',"BinEdges",linspace(-0.04,0.04,75),"Normalization","pdf");
                xlim = [-0.04 0.04];
                
                hold(app.UIAxes7,"on")


            if app.OverlayDistributionCheckBox.Value == true
                app.DistributionTypeDropDown.Enable = 'on';


                x = linspace(-0.05,0.05,500);
                if app.DistributionTypeDropDown.Value == 1
                    pd = fitdist(histData,'Normal');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["Normal Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)])];
                else
                    
                    pd = fitdist(histData,'tLocationScale');
                    y = pdf(pd,x);
                    plot(app.UIAxes7,x,y,"Color",'y',"LineStyle","--","LineWidth",1)
                    app.TextArea.Value = ["T-Student Distribution Fitted with parameters:", strjoin(["mu = " string(pd.mu)]) , strjoin(["sigma = " string(pd.sigma)]), strjoin(["nu = " string(pd.nu)])];
                end
                
            else
                app.DistributionTypeDropDown.Enable = 'off';
                app.TextArea.Value ="";
                
            end
            
            hold(app.UIAxes7,"off")
            
        end


        function SummaryStatistics(app)
            %% HeatMap e Summary statistic
            
            
            index = app.RetTable.Date > app.StartDateDatePicker_2.Value & app.RetTable.Date < app.EndDateDatePicker_2.Value;
            TempMatrix = app.RetMatrix(index,:);
            
            qvec = [0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99];
               
                meanv = mean(rmmissing(TempMatrix));
                vartt = var(rmmissing(TempMatrix));
                maxtt = max(rmmissing(TempMatrix));
                mintt = min(rmmissing(TempMatrix));
                stdtt = std(rmmissing(TempMatrix));
                skewtt = skewness(rmmissing(TempMatrix));
                kurtosistt = kurtosis(rmmissing(TempMatrix));
                
                quant_tt = quantile(rmmissing(TempMatrix),qvec)';
            
            
            tempTable = table(meanv',vartt',stdtt',maxtt',mintt',skewtt',kurtosistt');
            app.UITable5.Data = tempTable;
            
            for i = 1:length(quant_tt)
            tempTable{:,i+7} = quant_tt(:,i);
            end
            
            FXNAME = [{'USD/AUD' 'EUR/USD' 'USD/GBP' } {'AUD/GBP' 'EUR/GBP' 'AUD/EUR'}];
            
            for i = 1:length(qvec)
                quantileNames(i) = (strjoin(["Q." num2str(qvec(i))]));
            end
            
            
            app.UITable5.Data = tempTable;
            app.UITable5.RowName = FXNAME;
            app.UITable5.ColumnName = ["Mean" "Variance" "Std Dev" "Max" "Min" "Skew" "Kurtosis" quantileNames];
            
        end
    
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            pause(5)
            d = uiprogressdlg(app.UIFigure,"Message",'Starting Dashboard',"Title",'Please Wait: Dashboard Starting');
          
          
            %Disabilita i campi inserimento non per la plain vanilla
            app.BarrierValueEditField.Enable = 'off';
            app.BinaryValueEditField.Enable = 'off';
            app.BarrierTypeDropDown.Enable = 'off';
            app.OptionMaturityDatePicker.Limits = [datetime('01-01-1999',"Format","dd-MM-uuuu") datetime('01-01-2100',"Format","dd-MM-uuuu")];
            app.EndDateDatePicker.Value = datetime("today");
            app.Series1DropDown.ItemsData = [1 2 3 4 5 6];
            app.Series2DropDown.ItemsData = [0 1 2 3 4 5 6];
            app.EndDateDatePicker_3.Value = datetime("today");
            app.StartDateDatePicker_4.Value = app.EndDateDatePicker_3.Value-90;
            
 
            d.Value = .20;
            d.Message = 'Initializing Charts';
            %Grafici a torta inizializzazione
            ReSetHomePlots(app)
            
            
            %imposta formato decimali
            format bank;
            d.Value = .30;
            d.Message = ['Getting Data from Yahoo Finance and FRED ' char(174)];
            
            
            %Plot dati cambio su grafico
            TimeSeriesDataImport(app);
            YahooData(app)
            TimeSeriesPlotUpdate(app);
           
            d.Value = .40; 
           d.Message = 'Creating Correlation Heatmap';
            
% Call Generating Function
            Heatmap(app);            
            
            d.Value = .7;
            d.Message = 'Generating Summary Statistics';
            
            SummaryStatistics(app)

            
% Generate Return Distribution
            
            HistTimeSeries(app)

            d.Value = 1;
            d.Message = 'Operations Completed';
            pause(0.5)
        end

        % Button pushed function: AddtoPortfolioButton
        function AddtoPortfolioButtonPushed(app, event)
             app.counter = app.counter +1;
             d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Adding Option to Portfolio'); 
                       
             
%             Le Variabili ricevono i dati dagli elementi interattivi della
%             %dashboard
            OptionType = app.InstrumentTypeDropDown.Value;
            Strike = app.StrikeEditField.Value;
            LongShort = app.PositionButtonGroup.SelectedObject.Text;
            CallPut = app.OptionTypeButtonGroup.SelectedObject.Text;
            BinaryValue = app.BinaryValueEditField.Value;
            BarrierValue = app.BarrierValueEditField.Value;
            BarrierType = app.BarrierTypeDropDown.Value;
            MaturityDate = app.OptionMaturityDatePicker.Value;
            OptionStyle = app.OptionStyleButtonGroup.SelectedObject.Text;
            class(OptionStyle)


d.Value = .33; 
d.Message = 'Creating Portfolio';


%             If per cambio segno alla quantità per avere posizioni long e
%             short
            Test = 'Long';
            if strcmp(LongShort,Test) == 1
                app.Q = [app.Q app.QuantitySpinner.Value]; %riferimento a property perchè già esistente
                app.OptionTypeCategoricalLong = [app.OptionTypeCategoricalLong string(OptionType)];
                app.LongCategories_Quantities = [app.LongCategories_Quantities app.QuantitySpinner.Value];
                
                app.LongInstrumentCounter = app.LongInstrumentCounter +1;
            else
                app.Q = [app.Q -app.QuantitySpinner.Value];
                app.OptionTypeCategoricalShort = [app.OptionTypeCategoricalShort string(OptionType)];
                app.ShortCategories_Quantities = [app.ShortCategories_Quantities app.QuantitySpinner.Value];
                
                app.ShortInstrumentCounter = app.ShortInstrumentCounter+1;
                LongShort = 'Short';
            end
            
%             Stringa con nome strumento
            Name = OptionType;
            app.OptionTypeCategorical = [app.OptionTypeCategorical categorical(upper(cellstr(OptionType)))];
            
%             Vari if per definire lo strumento da aggiungere
            if strcmp(OptionType,'Vanilla') == 1
            app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate,'ExerciseStyle',OptionStyle, 'Name',Name)];
            
            end
            
            if strcmp(OptionType,'Binary') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"PayoffValue",BinaryValue,"Strike",Strike,"ExerciseDate", MaturityDate,'Name',Name)];
            end
            
            if strcmp(OptionType,'Barrier') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"BarrierValue",BarrierValue,"BarrierType",BarrierType,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
            
            if strcmp(OptionType, 'Lookback') == 1
                app.Instr = [app.Instr fininstrument(OptionType,"OptionType",CallPut,"Strike",Strike,"ExerciseDate",MaturityDate, 'Name',Name,"ExerciseStyle",OptionStyle)];
            end
           
            
     d.Value = .38; 
    d.Message = 'Option Added';       


%             Costruzione Pricer, anteposta per controllo degli strumenti ed
%             eliminazione
            Settle = app.Settle;


            BLSModel = finmodel("BlackScholes","Volatility",app.VolatilityEditField.Value);


% EUR CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'EUR'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveEUR,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveEUR,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

% USD CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'USD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveUSD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveUSD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end

            
% AUD CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'AUD'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveAUD,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveAUD,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
% GBP CASE            
            if app.UnderlyingDropDown.Value(1:3) == 'GBP'
                if or((strcmp(OptionType,'Lookback') == 1),(strcmp(OptionStyle,'American') == 1))  
                    app.Pricer = [app.Pricer finpricer("AssetMonteCarlo",'DiscountCurve',app.ZeroCurveGBP,"Model",BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous",'simulationDates',Settle+days(1):days(1):MaturityDate,'NumTrials',app.SimulationTrialsEditField.Value)];
                else
                    app.Pricer = [app.Pricer finpricer("analytic","DiscountCurve",app.ZeroCurveGBP,'Model',BLSModel,'SpotPrice',app.UnderlyingPriceEditField.Value,'DividendValue',app.YieldEditField.Value,'DividendType',"continuous")];
                end
            end
            
   
d.Value = .45; 
    d.Message = 'Pricing Model Created';            
            
            
           app.Currency = [app.Currency string(app.UnderlyingDropDown.Value)];
           
    
            app.TestInstr(1) = 0;
            if app.counter == 1
            else 
                for i = 1:app.counter-app.counter2-1
                    if (app.Instr(i) == app.Instr(app.counter-app.counter2)) & (app.Q(i)*app.Q(app.counter-app.counter2) > 0) & (app.Currency(i) == app.UnderlyingDropDown.Value)
                        app.TestInstr(i) = 1;
                    else
                        app.TestInstr(i) = 0;
                    end
                end
            end
            
            if nnz(app.TestInstr) > 0
                app.Currency(app.counter-app.counter2) = [];
                app.Instr(app.counter-app.counter2) = [];
                app.Pricer(app.counter-app.counter2) = [];
                app.Q(logical([app.TestInstr 0])) = app.Q(logical([app.TestInstr 0]))+ app.Q(app.counter-app.counter2);
                app.Q(app.counter-app.counter2) = [];
                app.OptionTypeCategorical(app.counter-app.counter2) = [];
                app.counter2 = app.counter2+1;
            end


if strcmp(OptionStyle,'American') == 1            
d.Value = .55; 
    d.Message = 'Simulating Underlying Paths';
else
    d.Value = .55; 
    d.Message = 'Black-Scholes Pricing';
end            
            
            Qty = app.Q;
            Instrum = app.Instr;
            
%             
%             Costruzione Pricer
%             

            
            [~, InstPrice, PortSens, app.InstSens] = PortCreationPricing(app,Instrum,Qty,app.Pricer);


if strcmp(OptionStyle,'American') == 1            
d.Value = .75; 
    d.Message = 'Simulation Completed';
else
    d.Value = .75; 
    d.Message = 'Option Correctly Priced';          
end            
            
%      Creazione Variabili info strumenti       
            for i = 1: length(app.Instr)
                OptionTypetoTABLE(i,1) = categorical(upper(app.Instr(i).OptionType));
                StriketoTABLE(i,1) = app.Instr(i).Strike;
                StyletoTABLE(i,1) = app.Instr(i).ExerciseStyle;
                DatetoTABLE(i,1) = app.Instr(i).ExerciseDate;
            
                if strcmp(app.OptionTypeCategorical(1,i),'BARRIER') == 1 %Maiuscolo perchè sopra c'è upper nella creazione del vettore
                    BValuetoTABLE(i,1) = app.Instr(i).BarrierValue;
                    BTypetoTABLE(i,1) = app.Instr(i).BarrierType;
                else
                    BValuetoTABLE(i,1) = '-';
                    BTypetoTABLE(i,1) = '-';
                
                end
                
                if strcmp(app.OptionTypeCategorical(1,i),'BINARY') == 1
                    BinVatoTABLE(i,1) = app.Instr(i).BinaryValue;
                else
                    BinVatoTABLE(i,1) = '-';
                end


            end
%     Creazione colonne nelle tabelle 
            app.InstSens.Quantity = app.Q';
            app.InstSens.Currency = categorical(app.Currency');
            app.InstSens.OptionType = app.OptionTypeCategorical';
            app.InstSens.Style = upper(StyletoTABLE);
            app.InstSens.CallPut = OptionTypetoTABLE;
            app.InstSens.Strike = StriketoTABLE;
            app.InstSens.BarrierValue = BValuetoTABLE;
            app.InstSens.BarrierType = BTypetoTABLE;
            app.InstSens.BinaryValue = BinVatoTABLE;
            app.InstSens.Maturity = DatetoTABLE;
            app.InstSens.Legend = zeros([height(app.InstSens(:,1)) 1]);
            app.InstSens.Lambda = [];
            app.InstSens.Properties.RowNames = {};


    d.Value = .95; 
    d.Message = 'Generating Tables and Charts';             


%       Update Plots
            
            UpdateHomePlots(app,InstPrice,app.InstSens,PortSens)


    d.Value = .100; 
    d.Message = 'Option Added Succesfully'; 
    pause(0.5)
        end

        % Value changed function: InstrumentTypeDropDown
        function InstrumentTypeDropDownValueChanged(app, event)
            value = app.InstrumentTypeDropDown.Value;
            if strcmp(value,'Barrier') == 1
                app.BarrierValueEditField.Enable = 'on';
                app.BarrierTypeDropDown.Enable = 'on';
            else
                app.BarrierValueEditField.Enable = 'off'; 
                app.BarrierTypeDropDown.Enable = 'off';
            end
            
            
            if strcmp(value,'Binary') == 1
                app.BinaryValueEditField.Enable = 'on';
                app.OptionStyleButtonGroup.Enable = 'off';
                app.EuropeanButton.Value = true;
            else
                app.BinaryValueEditField.Enable = 'off';
                app.OptionStyleButtonGroup.Enable = 'on';
            end
            
        
        end

        % Button pushed function: DeletePortfolioButton
        function DeletePortfolioButtonPushed(app, event)
            app.Instr = [];
            app.Q = [];
            app.Pricer = [];
            app.counter = 0;
            app.counter2 = 0;
            app.TestInstr = [];
            app.ShortInstrumentCounter = 0;
            app.LongInstrumentCounter = 0;
            app.OptionTypeCategoricalLong = [];
            app.OptionTypeCategoricalShort = [];
            app.LongCategories_Quantities = [];
            app.ShortCategories_Quantities = [];
            app.OptionTypeCategorical = [];
            app.Currency = [];
%        Reset Plots and Tables
            ReSetHomePlots(app)
            
        end

        % Button pushed function: SetDateandRatesButton
        function SetDateandRatesButtonPushed(app, event)
            app.SetDateandRatesButton.Enable = 'off';
            app.RateDialog = RateandSettleDialog(app);
            app.StorePortfolioButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.RateDialog)
            delete(app.CurrencySetError)
            delete(app)


        end

        % Value changed function: UnderlyingDropDown
        function UnderlyingDropDownValueChanged(app, event)
            value = app.UnderlyingDropDown.Value(1:3);
            
% IMPOSTAZIONE DELL?UNDERLYING PRICE COME ULTIMO TASSO DI CAMBIO REGISTRATO
            
           
            if isempty(app.ZeroCurveEUR) == 1
                TEST(1) = 0;
            else
                TEST(1) = 1;
            end
            
            if isempty(app.ZeroCurveUSD) == 1
                TEST(2) = 0;
            else
                TEST(2) = 1;
            end
            
            if isempty(app.ZeroCurveAUD) == 1
                TEST(3) = 0;
            else
                TEST(3) = 1;
            end
            
            if isempty(app.ZeroCurveGBP) == 1
                TEST(4) = 0;
            else
                TEST(4) = 1;
            end
            
            CurrencyString = ["EUR" "USD" "AUD" "GBP"];
            
            
% Aggiungere errori            
            if TEST(1) == 0 & value == 'EUR'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.UnderlyingDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            if TEST(2) == 0 & value == 'USD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.UnderlyingDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            
            if TEST(3) == 0 & value == 'AUD'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.UnderlyingDropDown.Value(1:3) = CurrencyString(ind);
            end
            
            
            if TEST(4) == 0 & value == 'GBP'
               app.CurrencySetError = CurrencySetError(app);
               ind = find(TEST,1);
               app.UnderlyingDropDown.Value(1:3) = CurrencyString(ind);
            end


        end

        % Value changed function: Series2DropDown
        function Series2DropDownValueChanged(app, event)
            value = app.Series2DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: Series1DropDown
        function Series1DropDownValueChanged(app, event)
            value = app.Series1DropDown.Value;
            TimeSeriesPlotUpdate(app)
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            value = app.StartDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            value = app.EndDateDatePicker.Value;
            TimeSeriesPlotUpdate(app)
            
        end

        % Value changed function: StartDateDatePicker_2
        function StartDateDatePicker_2ValueChanged(app, event)
            value = app.StartDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: EndDateDatePicker_2
        function EndDateDatePicker_2ValueChanged(app, event)
            value = app.EndDateDatePicker_2.Value;
            Heatmap(app)
            SummaryStatistics(app)
        end

        % Value changed function: BackgroundColorButton
        function BackgroundColorButtonValueChanged(app, event)
            value = app.BackgroundColorButton.Value;
            if value == true
                app.BackgroundColorButton.BackgroundColor = [0.15 0.15 0.15];
                app.BackgroundColorButton.FontColor = [1 1 1];
                app.CorrelationMapPanel.ForegroundColor = [0 0 0];
                app.CorrelationMapPanel.BackgroundColor = [1 1 1];
            else
                app.BackgroundColorButton.BackgroundColor = [1 1 1];
                app.BackgroundColorButton.FontColor = [0 0 0];
                app.CorrelationMapPanel.ForegroundColor = [1 1 1];
                app.CorrelationMapPanel.BackgroundColor = [0.15 0.15 0.15];
            end
            
        end

        % Value changed function: CurrencyDropDown
        function CurrencyDropDownValueChanged(app, event)
            value = app.CurrencyDropDown.Value;
            HistTimeSeries(app)
        end

        % Value changed function: StartDateDatePicker_3
        function StartDateDatePicker_3ValueChanged(app, event)
            value = app.StartDateDatePicker_3.Value;
            HistTimeSeries(app)
        end

        % Value changed function: OverlayDistributionCheckBox
        function OverlayDistributionCheckBoxValueChanged(app, event)
            value = app.OverlayDistributionCheckBox.Value;
            
            HistTimeSeries(app)
            
            
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            
           HistTimeSeries(app)
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.RiskHorizonEditField.Value;
            HistTimeSeries(app)
        end

        % Selection changed function: OptionStyleButtonGroup
        function OptionStyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionStyleButtonGroup.SelectedObject.Text;
            
            if selectedButton == 'American'
                app.SimulationTrialsEditField.Enable = 'on';
            else
                app.SimulationTrialsEditField.Enable = 'off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
d = uiprogressdlg(app.UIFigure,"Title","Please Wait. Generating Stepladder","Message","Obtaining portfolio data","Value",0)
%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(app.Instr)
Portfolio{1,i} = app.Instr(i);
Portfolio{2,i} = app.Q(i);
Portfolio{3,i} = app.Pricer(i);
Portfolio{4,i} = app.Currency(i);
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end

%CREAZIONE DEL PORTAFOGLIO CON LE OPZIONI RICHIESTE
Temp_Portfolio = Portfolio(1:3,logical(TESTV));

%CONVERSIONE IN UN PORTAFOGLIO CHE PUò ESSERE PREZZATO CON FINPORTFOLIO
for i = 1:size(Temp_Portfolio,2)
    TempInstruments(1,i) = Temp_Portfolio{1,i}; 
    TempQuantities(1,i) = Temp_Portfolio{2,i};
    TempPricer(1,i) = Temp_Portfolio{3,i};
end

d.Message = "Creating Stepladder";
d.Value = 0.2;


                Idx = app.RetTable.Date > datetime("today")-calmonths(app.SamplingPeriodDropDown.Value);
                TempMatrix = app.RetMatrix(Idx,app.StepladderCurrencyDropDown.Value);
                
                
                LastPrice = app.PriceMatrix(end,app.StepladderCurrencyDropDown.Value);
                if isnan(LastPrice) == 1
                    LastPrice = app.Prices{app.StepladderCurrencyDropDown.Value}(end);
                end
                RiskHorizonFactor = sqrt(app.RiskHorizonEditField.Value);    
                sq = std(rmmissing(TempMatrix)); % STANDARD DEVIATION OF SELECTED RETURNS


d.Message ="Obtaining quantiles";
%CREAZIONE SCENARI
qvec = [0.001 0.01 0.05 0.10 0.25 0.5 0.75 0.90 0.95 0.99 0.999];
Quantili = quantile(rmmissing(TempMatrix),qvec);
S0Scenery= exp(Quantili.*RiskHorizonFactor)'*LastPrice;
app.TextArea_2.Value = ["Current Underlying Price:" string(app.Prices{app.StepladderCurrencyDropDown.Value}(end))];


%APPLICAZIONE DEGLI SCENARI AI PRICER E AGGIUNTA DI UNO SCENARIO
%CORRISPONDENTE AL VALORE SPOT DEL TASSO DI CAMBIO (PORTAFOGLIO INIZIALE)

S0Scenery_StartingPrice = [S0Scenery ; LastPrice];

zero = zeros([length(S0Scenery_StartingPrice) 1]);
app.SummaryTable = table(zero, zero, zero, zero, zero, zero, zero, 'VariableNames',{'Price' 'Delta' 'Greca2' 'Greca3' 'Greca4' 'Greca5' 'Greca6'});
 % PREALLOCAZIONE

d.Message = "Generating portfolio price scenarios" ;
d.Value = 0.4;
for i = 1:length(S0Scenery_StartingPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = S0Scenery_StartingPrice(i);
    end
    [~, ~, PortSens, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    app.SummaryTable(i,:) = PortSens;
    
end

Loss = table(app.SummaryTable{1:end-1,1}-app.SummaryTable{end,1},'VariableNames',"Loss");
Tmp_TABLE = [table(S0Scenery,'VariableNames',"Scenery") table(log(S0Scenery_StartingPrice(1:end-1)./S0Scenery_StartingPrice(end)),'VariableNames',"Variation") Loss app.SummaryTable(1:end-1,:)];

%ADDING BACKGROUND TO GAINS AND LOSSES

[Sorted , indexes] = sort(Tmp_TABLE{:,3});
[PRR, ~ ] = find(Sorted < 0);
[PRG, ~] = find(Sorted > 0);

RedCol = [linspace(0.4,0.8,length(qvec))' zeros([length(qvec) 2])];
GreenCol = [ zeros([length(qvec) 1]) linspace(0.8,0.4,length(qvec))' zeros([length(qvec) 1])];

colInR = round(linspace(1,size(RedCol,1),length(PRR)))';
colInG = round(linspace(1,size(GreenCol,1),length(PRG)))';

for i = 1:length(PRR)
    
    s = uistyle("BackgroundColor",RedCol(colInR(i),:));
    addStyle(app.UITable6,s,'cell',[indexes(PRR(i)) 3]);
end

for i = 1:length(PRG)
    
    s = uistyle("BackgroundColor",GreenCol(colInG(i),:));
    addStyle(app.UITable6,s,'cell',[indexes(PRG(i)) 3]);
end


app.UITable6.Data = Tmp_TABLE;
app.UITable6.ColumnName = {'Scenarios' 'Price Var. (%)' 'Gains/Losses'  ' Portfolio Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};


app.UITable7.Data = app.SummaryTable(end,:);
app.UITable7.ColumnName = {'Price' 'Delta' 'Gamma' 'Lambda' 'Vega' 'Theta' 'Rho'};
app.UITable7.RowName = {'Selected Currency Portfolio Data'};


d.Message = "Creating VaR and ES scenarios";
d.Value = 0.7;
%VAR ES TABLE STATISTICAL APPROACH 
VarESQvec = [1-0.999 1-0.99 1-0.95]';
SelectedSchockMethod = app.VaRandESMethodDropDown.Value;

fit = fitdist(rmmissing(TempMatrix),"tLocationScale");
Qvec1 = [norminv(VarESQvec) tinv(VarESQvec,fit.nu) ];

%A SECONDA DELLA MODALITà SELEZIONATA, ELABORA DEGLI SCENARI DEL TASSO DI
%CAMBIO IN DIMINUZIONE


if app.SummaryTable.Delta(end) > 0 && app.SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(sq.*Qvec1(:,SelectedSchockMethod).*RiskHorizonFactor).*LastPrice;
    
    if SelectedSchockMethod == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*RiskHorizonFactor)*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec))*RiskHorizonFactor);
        ES_Scenarios = shock.*LastPrice;
    end
    
end

if app.SummaryTable.Delta(end) < 0 && app.SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(-sq.*Qvec1(:,SelectedSchockMethod).*RiskHorizonFactor).*LastPrice;
    
    if SelectedSchockMethod == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*RiskHorizonFactor)*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec))*RiskHorizonFactor);
        ES_Scenarios = shock.*LastPrice;
    end
end
if app.SummaryTable.Delta(end) > 0 && app.SummaryTable.Price(end) < 0
    
         VAR_Scenerios = exp(sq.*Qvec1(:,SelectedSchockMethod).*RiskHorizonFactor).*LastPrice;
    
    if SelectedSchockMethod == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*RiskHorizonFactor)*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec))*RiskHorizonFactor);
        ES_Scenarios = shock.*LastPrice;
    end
    
   
    end

if app.SummaryTable.Delta(end) < 0 && app.SummaryTable.Price(end) < 0
  VAR_Scenerios = exp(-sq.*Qvec1(:,SelectedSchockMethod).*RiskHorizonFactor).*LastPrice;
    
    if SelectedSchockMethod == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq.*RiskHorizonFactor)*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec))*RiskHorizonFactor);
        ES_Scenarios = shock.*LastPrice;
    end
end


ES_Scenarios_SpotPrice = [ES_Scenarios ; LastPrice ];
VAR_Scenarios_SpotPrice = [VAR_Scenerios ; LastPrice];


SummaryTableVAR = array2table(zeros([length(VAR_Scenarios_SpotPrice) 7])); %PREALLOCAZIONE
for i = 1:length(VAR_Scenarios_SpotPrice)
    for k= 1:length(TempPricer) 
        TempPricer(k).SpotPrice = VAR_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensVAR, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableVAR(i,:) = PortSensVAR;
    
end

SummaryTableES = array2table(zeros([length(ES_Scenarios_SpotPrice) 7])); %PREALLOCAZIONE

for i = 1:length(ES_Scenarios_SpotPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = ES_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensES, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableES(i,:) = PortSensES;
    
end
d.Value = 0.95;
ES = SummaryTableES{end,1}-SummaryTableES{1:end-1,1};
VaR= SummaryTableVAR{end,1}-SummaryTableVAR{1:end-1,1};
Alpha = ["99.9%" "99%" "95%"];


app.UITable8.Data = table(string(Alpha'),VAR_Scenarios_SpotPrice(1:end-1),log(VAR_Scenarios_SpotPrice(1:end-1)/VAR_Scenarios_SpotPrice(end)),VaR, ES_Scenarios_SpotPrice(1:end-1),log(ES_Scenarios_SpotPrice(1:end-1)/ES_Scenarios_SpotPrice(end)) , ES ,'VariableNames',{'Alpha' 'VaR Scenario' 'VaR Applied Shock (%)'  'VaR' 'ES Scenario' 'ES Applied Shock (%)'  'CVaR' });
app.UITable8.ColumnName = {'Alpha' 'VaR Scenario' 'VaR Applied Shock (%)'  'VaR' 'ES Scenario' 'ES Applied Shock (%)'  'ES' };

addStyle(app.UITable8,uistyle("HorizontalAlignment",'center'),'column',1:7);

d.Value = 1;
d.Message = "Stepladder created";
pause(0.5)


        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
d = uiprogressdlg(app.UIFigure,"Title","Please Wait. Backtesting VaR","Message","Obtaining portfolio data","Value",0);


%CREAZIONE PORTAFOGLIO PER RICAVARE LE OPZIONI DENOMINATE NEL TASSO DI
%CAMBIO SELEZIONATO
for i = 1:length(app.Instr)
Portfolio{1,i} = app.Instr(i);
Portfolio{2,i} = app.Q(i);
Portfolio{3,i} = app.Pricer(i);
Portfolio{4,i} = app.Currency(i);
Portfolio{5,i} = replace(Portfolio{4,i},["USD/AUD" "EUR/USD" "USD/GBP" "AUD/GBP" "EUR/GBP" "AUD/EUR"],["1" "2" "3" "4" "5" "6"]);
Portfolio{5,i} = str2double(Portfolio{5,i});
end

%VETTORE LOGICO PER IDENTIFICARLE
for i = 1:size(Portfolio,2)
    if Portfolio{5,i} == app.StepladderCurrencyDropDown.Value
        TESTV(i) = 1;
    else
        TESTV(i) = 0;
    end
end
d.Value = 0.15;

%CREAZIONE DEL PORTAFOGLIO CON LE OPZIONI RICHIESTE
Temp_Portfolio = Portfolio(1:3,logical(TESTV));

%CONVERSIONE IN UN PORTAFOGLIO CHE PUò ESSERE PREZZATO CON FINPORTFOLIO
for i = 1:size(Temp_Portfolio,2)
    TempInstruments(1,i) = Temp_Portfolio{1,i}; 
    TempQuantities(1,i) = Temp_Portfolio{2,i};
    TempPricer(1,i) = Temp_Portfolio{3,i};
end

            
                        SelectedData1 = app.FredData{app.StepladderCurrencyDropDown.Value};
                        Min_Date1 = min(SelectedData1{:,1});
                        app.VaRBacktestingHorizonDatePicker.Limits = [Min_Date1 datetime("today")-1];


                         LastPrice = app.PriceMatrix(end,app.StepladderCurrencyDropDown.Value);
                        if isnan(LastPrice) == 1
                            LastPrice = app.Prices{app.StepladderCurrencyDropDown.Value}(end);
                        end
                        RiskHorizonFactor = sqrt(app.RiskHorizonEditField.Value);  
                        StartDate = app.VaRBacktestingHorizonDatePicker.Value; 
                        TestWindow = app.RetTable.Date(app.RetTable.Date > StartDate);

d.Message = "Calculating VaR on historical data";
d.Value = 0.2;
              
value = 0;
F = 0;
for t = 1:length(TestWindow)
    time = TestWindow(t);
   F = F+1;
   
d.Value = value +t/length(TestWindow);   
    
idx = app.RetTable.Date > time-calmonths(app.SamplingRollingWindowDropDown.Value) & app.RetTable.Date < time;
VARTempMatrix = app.RetMatrix(idx,app.StepladderCurrencyDropDown.Value);

sq = std(rmmissing(VARTempMatrix));


%VAR ES TABLE STATISTICAL APPROACH 
VarESQvec = [1-0.999 1-0.99 1-0.95]';

fit = fitdist(rmmissing(VARTempMatrix),"tLocationScale");
Qvec1 = [norminv(VarESQvec) tinv(VarESQvec,fit.nu) ];

%A SECONDA DELLA MODALITà SELEZIONATA, ELABORA DEGLI SCENARI DEL TASSO DI
%CAMBIO IN DIMINUZIONE


if app.SummaryTable.Delta(end) > 0 && app.SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*LastPrice;
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*LastPrice;
    end
end
if app.SummaryTable.Delta(end) < 0 && app.SummaryTable.Price(end) > 0
    VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*LastPrice;
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*LastPrice;
    end
end
if app.SummaryTable.Delta(end) > 0 && app.SummaryTable.Price(end) < 0
    
            VAR_Scenerios = exp(sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*LastPrice;
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(-VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(-sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*LastPrice;
    end
    
   
    end

if app.SummaryTable.Delta(end) < 0 && app.SummaryTable.Price(end) < 0
   VAR_Scenerios = exp(-sq.*Qvec1(:,app.VaRandESMethodDropDown.Value)).*LastPrice;
    
    if app.VaRandESMethodDropDown.Value == 1
    
        ES_Scenarios = exp(VarESQvec.^-1 .* normpdf(norminv(VarESQvec))*sq).*LastPrice;
    else
        tStud_PDF = tpdf(tinv(VarESQvec,fit.nu),fit.nu);
        shock = exp(sq.*((fit.nu+Qvec1(:,2).^2)./(fit.nu-1)).*(tStud_PDF./(VarESQvec)));
        ES_Scenarios = shock.*LastPrice;
    end
end


ES_Scenarios_SpotPrice = [ES_Scenarios ; LastPrice ];
VAR_Scenarios_SpotPrice = [VAR_Scenerios ; LastPrice];

for i = 1:length(VAR_Scenarios_SpotPrice)
    for k= 1:length(TempPricer) 
        TempPricer(k).SpotPrice = VAR_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensVAR, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableVAR(i,:) = PortSensVAR;
    
end


for i = 1:length(ES_Scenarios_SpotPrice)
    for k= 1:length(TempPricer)
        TempPricer(k).SpotPrice = ES_Scenarios_SpotPrice(i);
    end
    [~, ~, PortSensES, ~] = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    SummaryTableES(i,:) = PortSensES;
    
end

ES(1:3,F) = SummaryTableES{end,1}-SummaryTableES{1:end-1,1};
VaR(1:3,F) = SummaryTableVAR{end,1}-SummaryTableVAR{1:end-1,1};
end
plot(app.UIAxes8,TestWindow,VaR(3,:),TestWindow,VaR(2,:),TestWindow,VaR(1,:))
hold(app.UIAxes8,"on")


if app.VaRLimitMlnEditField.Value == 0
    hold(app.UIAxes8,"off")
    legend(app.UIAxes8,{'VaR 95' 'VaR 99' 'VaR 99.9'},"Box","on","EdgeColor",'w',"TextColor",'w')
else
    plot(app.UIAxes8,TestWindow,repmat(app.VaRLimitMlnEditField.Value,1, size(VaR,2)),'--')
    hold(app.UIAxes8,"off")
    legend(app.UIAxes8,{'VaR 95' 'VaR 99' 'VaR 99.9' 'Limit'},"Box","on","EdgeColor",'w',"TextColor",'w')
end


d.Message = "Historical data processed";
pause(1)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%ù

d.Message = "Checking VaR limits exceptions";
d.Value = 0;

%VAR LIMITS EXCEPTIONS
app.StartDateDatePicker_4.Limits = [TestWindow(1) datetime("today")];
app.EndDateDatePicker_3.Limits = [app.StartDateDatePicker_4.Value+1 datetime("today")];

DateIndex = app.RetTable.Date >= TestWindow(1);
Excep_PRICE_Matrix = app.PriceMatrix(DateIndex,app.StepladderCurrencyDropDown.Value);

Logic = isnan(Excep_PRICE_Matrix); %Trova le righe dove sono situati gli nan
PricesToPricer = Excep_PRICE_Matrix(~Logic);

Dates_NaN = app.RetTable.Date(DateIndex);
Dates_NO_NaN = Dates_NaN(~Logic); % PRENDE LE DATE NON CORRISPONDENTI AD UN NAN         
 

d.Value = 0.5;

for i = 1:length(PricesToPricer)
    for k = 1:length(TempPricer)
        TempPricer(k).SpotPrice = PricesToPricer(i);
    end
    PortPrice = pricePortfolio(finportfolio(TempInstruments,TempPricer,TempQuantities));
    PortPriceArray(i,1) = PortPrice;
end

PortfolioGL = [0; diff(PortPriceArray(:,1))];

d.Value = 0.6;


ZoomInd   = (Dates_NO_NaN >= app.StartDateDatePicker_4.Value) & (Dates_NO_NaN <= app.EndDateDatePicker_3.Value);
VaR_NO_NaN = VaR(:,~Logic)';
VaRData   = [-VaR_NO_NaN(ZoomInd,3) -VaR_NO_NaN(ZoomInd,2) -VaR_NO_NaN(ZoomInd,1)];
VaRFormat = {'-','--','-.'};
D = Dates_NO_NaN(ZoomInd);
R = PortfolioGL(ZoomInd);
N95 = VaRData(:,1);
N99 = VaRData(:,2);
N999 = VaRData(:,3);
IndN99    = (R < N99);
IndN999   = (R < N999);
IndN95 = (R < N95);

d.Value = 0.7;


bar(app.UIAxes9,D,R,0.5,'FaceColor',[0.7 0.7 0.7]);
hold(app.UIAxes9,"on")
for i = 1 : size(VaRData,2)
    stairs(app.UIAxes9,D-0.5,VaRData(:,i),VaRFormat{i});
end
ylabel(app.UIAxes9,'VaR')
xlabel(app.UIAxes9,'Date')
legend(app.UIAxes9,{'Portfolio Gains/Losses','VaR95','VaR99','VaR99.9'},'Location','Best','AutoUpdate','Off',"Box","on","EdgeColor",'w',"TextColor",'w')


d.Value = 0.9;


plot(app.UIAxes9,D(IndN95),N95(IndN95),'o',D(IndN99),N99(IndN99),'o',...
   D(IndN999),N999(IndN999),'o','MarkerSize',8,'LineWidth',1.5)
xlim(app.UIAxes9,[D(1)-1, D(end)+1])
hold(app.UIAxes9,"off")

d.Value = 1;
d.Message = "Operation completed";

SelectedCurrencyFX = app.StepladderCurrencyDropDown.Items(app.StepladderCurrencyDropDown.Value);
VaRType = convertStringsToChars(string(app.VaRandESMethodDropDown.Items(app.VaRandESMethodDropDown.Value)));

vbt = varbacktest(PortfolioGL,VaR_NO_NaN,"PortfolioID",SelectedCurrencyFX,"VaRLevel",[0.999 0.99 0.95],"VaRID",{[VaRType ' 99.9%'],[VaRType ' 99%'], [VaRType ' 95%'] });

SummaryVB = summary(vbt);
app.UITable10.Data = SummaryVB;
app.UITable10.ColumnName = SummaryVB.Properties.VariableNames;
        end

        % Button pushed function: ImportOptionsDataButton
        function ImportOptionsDataButtonPushed(app, event)
            app.file = uigetfile();
        end

        % Button pushed function: GenerateChartButton
        function GenerateChartButtonPushed(app, event)
            SPX = table2array(readtable(app.file));
            S0=770.05;
            r=0.66/100;

            Maturity=SPX(3:end,2);          % maturity
            Strike=SPX(3:end,3);            % moneyness
            CallPrice=SPX(3:end,4);         % option prices


            s = VolSurface(app.UIAxes10,S0, r, Maturity, Strike, CallPrice);

        end

        % Button pushed function: StorePortfolioButton
        function StorePortfolioButtonPushed(app, event)


            app.StoreDataButtonCounter = app.StoreDataButtonCounter +1; 
            Counter = app.StoreDataButtonCounter;
            
            if Counter == 1
                if exist(app.PortfolioData) == 1
                    NC = size(app.PortfolioData,1) +1 ;
                else
                    app.PortfolioData = {};
                    NC = 1;
                
                end
            else
                NC = size(app.PortfolioData,1) +1;
            end


             app.PortfolioData{NC,1} = app.Settle;
             app.PortfolioData{NC,2} = app.Q;
             app.PortfolioData{NC,3} = app.Pricer;
             app.PortfolioData{NC,4} = app.Instr;
             app.PortfolioData{NC,5} = app.InstSens.Currency;
             app.PortfolioData{NC,6} = app.InstSens.OptionType;
             app.PortfolioData{NC,7} = app.InstSens.CallPut;
             app.PortfolioData{NC,8} = app.InstSens.Strike;
             app.PortfolioData{NC,9} = app.InstSens.Maturity;
             app.PortfolioData{NC,10} = app.InstSens;
            
            tete = app.PortfolioData;
            
            
            app.StorePortfolioButton.Enable = 'off';
            app.DeletePortfolioButtonPushed(app)
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            SelectedCurrency = app.UnderlyingDropDown_2.Items(app.UnderlyingDropDown_2.Value);
            
            
     PortfolioData = app.PortfolioData;       
     Currency = SelectedCurrency;       
            
                NewPortfolio = {};
    SummaryTable = table();
for i = 1:size(PortfolioData,1)
    Dates(i,1) = PortfolioData{i,1};
end

    SummaryTable.Date = Dates;

% INIZIALIZZAZIONE
    TempInstr = PortfolioData{1,4};
    TempPricer = PortfolioData{1,3};
    TempQ = PortfolioData{1,2};
    TempCurrency = PortfolioData{1,5};
    TempOptionType = PortfolioData{1,6};
    TempCallPut = PortfolioData{1,7};
    TempStrike = PortfolioData{1,8};
    TempMaturity = PortfolioData{1,9};


    SummaryTable.NetPosition(1) = sum(TempQ(TempCurrency == Currency));
    SummaryTable.LongNumber(1) = nnz(TempQ(TempCurrency == Currency) > 0);
    SummaryTable.ShortNumber(1) = nnz(TempQ(TempCurrency == Currency) < 0);
    SummaryTable.CallNumber(1) = sum(TempQ(TempCallPut == "CALL" & (TempCurrency == Currency)));
    SummaryTable.PutNumber(1) = sum(TempQ((TempCallPut == "PUT") & (TempCurrency == Currency)));
    SummaryTable.MeanExpiration(1) = mean(years(TempMaturity(TempCurrency == Currency)-Dates(1)));
    SummaryTable.VanillaNumber(1) = nnz(TempOptionType(TempCurrency == Currency) == "VANILLA"); 
    SummaryTable.BarrierNumber(1) = nnz(TempOptionType(TempCurrency == Currency) == "BARRIER"); 
    SummaryTable.BinaryNumber(1) = nnz(TempOptionType(TempCurrency == Currency) == "BINARY"); 
    SummaryTable.LookbackNumber(1) = nnz(TempOptionType(TempCurrency == Currency) == "LOOKBACK"); 


% CONTROLLO ALTRI GIORNI
if size(PortfolioData,1) > 1

    
    for i = 2:size(PortfolioData,1)    
    
    % Ottieni Dati Giorno Precedente
    Instr1 = PortfolioData{i-1,4};
    Pricer1 = PortfolioData{i-1,3};
    Q1 = PortfolioData{i-1,2};
    Currency1 = PortfolioData{i-1,5};
    OptionType1 = PortfolioData{i-1,6};
    CallPut1 = PortfolioData{i-1,7};
    Strike1 = PortfolioData{i-1,8};
    Maturity1 = PortfolioData{i-1,9};
    
    %Ottieni dati giorno i
    Instr2 = PortfolioData{i,4};
    Pricer2 = PortfolioData{i,3};
    Q2 = PortfolioData{i,2};
    Currency2 = PortfolioData{i,5};
    OptionType2 = PortfolioData{i,6};
    CallPut2 = PortfolioData{i,7};
    Strike2 = PortfolioData{i,8};
    Maturity2 = PortfolioData{i,9};
    
    
    CurrencyQ1 = Q1(Currency1 == Currency);
    CurrencyInstr1 = Instr1(Currency1 == Currency);
    CurrencyPricer = TempPricer(Currency1 == Currency);
    
    CurrencyQ2 = Q2(Currency2 == Currency);
    CurrencyInstr2 = Instr2(Currency2 == Currency);
    CurrencyPricer = TempPricer(Currency2 == Currency);

TEST = zeros(length(CurrencyInstr2),length(CurrencyInstr1));
for i = 1:length(CurrencyInstr2)
    for k = 1:length(CurrencyInstr1)
        if CurrencyInstr2(i)== CurrencyInstr1(k)
            TEST(i,k) = 1;
        else
            TEST(i,k) = 0;
        end         
    end
end

    TEST = logical(TEST);
    NewPricer = Pricer1;
    NewInstr = Instr1;
    NewQ = CurrencyQ1;
    NewInstADDED_Counter = 0;
    for i = 1:length(Instr2)
        if nnz(TEST(i,:)) == 0
            NewInstr(length(NewInstr)+1) = Instr2(i);
            NewPricer(length(NewPricer)+1) = Pricer2(i);
            NewQ(length(NewQ)+1) = CurrencyQ2(i);
            NewInstADDED_Counter = NewInstADDED_Counter +1; 
        else
            Ind = logical([TEST(i,:) zeros([1 NewInstADDED_Counter])]);
           NewQ(Ind) =  sum([CurrencyQ1(TEST(i,:)) CurrencyQ2(i)]);
    end
            
    end
    end
    
else
end


            %RICHIAMO FUNZIONE PER GENERARE LA SUMMARY TABLE
            SummaryTable = SummaryTableData(app.PortfolioData,app.UnderlyingDropDown_2.Items(app.UnderlyingDropDown_2.Value));
            
            app.UITable11.Data = SummaryTable;
            app.UITable11.ColumnName = {'Portfolio Date' 'Net Long/Short Pos.' 'Long Number' 'Short Number' 'Call Number' 'Put Number' 'Mean Time to Exp.' 'Vanilla Options' 'Barrier Options' 'Binary Options' 'Lookback Options'};
            
        end

        % Value changed function: UnderlyingDropDown_2
        function UnderlyingDropDown_2ValueChanged(app, event)
            value = app.UnderlyingDropDown_2.Value;
            SelectedCurrency = app.UnderlyingDropDown_2.Items(value);
            
            app.USDAUDPORTFOLIOLabel.Text = strjoin([convertCharsToStrings(SelectedCurrency) "PORTFOLIO"]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Risk Management Dashboard for Option Trading Desk v1.0 - The Black Swan Team';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortfolioSelectionTab = uitab(app.TabGroup);
            app.PortfolioSelectionTab.Title = 'Portfolio Selection';
            app.AddOptiontoPortfolioPanel = uipanel(app.PortfolioSelectionTab);
            app.AddOptiontoPortfolioPanel.Title = 'Add Option to Portfolio';
            app.SetDateandRatesButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.SetDateandRatesButton.ButtonPushedFcn = createCallbackFcn(app, @SetDateandRatesButtonPushed, true);
            app.SetDateandRatesButton.Text = 'Set Date and Rates';
            app.AddtoPortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.AddtoPortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoPortfolioButtonPushed, true);
            app.AddtoPortfolioButton.Enable = 'off';
            app.AddtoPortfolioButton.Text = 'Add to Portfolio';
            app.DeletePortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.DeletePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePortfolioButtonPushed, true);
            app.DeletePortfolioButton.Text = 'Delete Portfolio';
            app.OptionStyleButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionStyleButtonGroupSelectionChanged, true);
            app.OptionStyleButtonGroup.Title = 'Option Style';
            app.EuropeanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.EuropeanButton.Text = 'European';
            app.EuropeanButton.Value = true;
            app.AmericanButton = uiradiobutton(app.OptionStyleButtonGroup);
            app.AmericanButton.Text = 'American';
            app.PositionButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.PositionButtonGroup.Title = 'Position';
            app.LongButton = uiradiobutton(app.PositionButtonGroup);
            app.LongButton.Text = 'Long';
            app.LongButton.Value = true;
            app.ShortButton = uiradiobutton(app.PositionButtonGroup);
            app.ShortButton.Text = {'Short'; ''};
            app.OptionTypeButtonGroup = uibuttongroup(app.AddOptiontoPortfolioPanel);
            app.OptionTypeButtonGroup.Title = 'Option Type';
            app.CallButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.CallButton.Text = 'Call';
            app.CallButton.Value = true;
            app.PutButton = uiradiobutton(app.OptionTypeButtonGroup);
            app.PutButton.Text = 'Put';
            app.InstrumentTypeDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDown.Items = {'Vanilla', 'Binary', 'Barrier', 'Lookback'};
            app.InstrumentTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @InstrumentTypeDropDownValueChanged, true);
            app.InstrumentTypeDropDown.Value = 'Vanilla';
            app.InstrumentTypeDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.InstrumentTypeDropDownLabel.Text = 'Instrument Type';
            app.StrikeEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.StrikeEditField.Limits = [0 Inf];
            app.StrikeEditField.Value = 90;
            app.StrikeEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.StrikeEditFieldLabel.Text = 'Strike';
            app.UnderlyingPriceEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingPriceEditFieldLabel.Text = 'Underlying Price';
            app.UnderlyingPriceEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.UnderlyingPriceEditField.Value = 90;
            app.VolatilityEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.VolatilityEditFieldLabel.Text = 'Volatility';
            app.VolatilityEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.VolatilityEditField.Value = 0.12;
            app.OptionMaturityDatePickerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePickerLabel.Text = 'Option Maturity';
            app.OptionMaturityDatePicker = uidatepicker(app.AddOptiontoPortfolioPanel);
            app.OptionMaturityDatePicker.Value = datetime([2021 3 3]);
            app.YieldEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.YieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.YieldLabel.Text = 'Yield (%)';
            app.BarrierPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BarrierPanel.Title = 'Barrier Panel';
            app.GridLayout = uigridlayout(app.BarrierPanel);
            app.BarrierTypeDropDown = uidropdown(app.GridLayout);
            app.BarrierTypeDropDown.Items = {'Up Knock-In', 'Up Knock-Out', 'Down Knock-In', 'Down Knock-Out'};
            app.BarrierTypeDropDown.ItemsData = {'UI', 'UO', 'DI', 'DO'};
            app.BarrierTypeDropDown.Value = 'UI';
            app.BarrierTypeLabel = uilabel(app.GridLayout);
            app.BarrierTypeLabel.Text = 'Barrier Type ';
            app.BarrierValueLabel = uilabel(app.GridLayout);
            app.BarrierValueLabel.Text = 'Barrier Value ';
            app.BarrierValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.BarrierValueEditField.Limits = [0 Inf];
            app.BinaryPanel = uipanel(app.AddOptiontoPortfolioPanel);
            app.BinaryPanel.Title = 'Binary Panel';
            app.GridLayout2 = uigridlayout(app.BinaryPanel);
            app.BinaryValueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BinaryValueEditField.Limits = [0 Inf];
            app.BinaryValueEditFieldLabel = uilabel(app.GridLayout2);
            app.BinaryValueEditFieldLabel.Text = 'Binary Value';
            app.SimulationTrialsEditFieldLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.SimulationTrialsEditFieldLabel.Text = 'Simulation Trials';
            app.SimulationTrialsEditField = uieditfield(app.AddOptiontoPortfolioPanel, 'numeric');
            app.SimulationTrialsEditField.Enable = 'off';
            app.SimulationTrialsEditField.Value = 1000;
            app.QuantitySpinner = uispinner(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinner.Limits = [0 Inf];
            app.QuantitySpinner.Value = 1;
            app.QuantitySpinnerLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.QuantitySpinnerLabel.Text = 'Quantity';
            app.UnderlyingDropDownLabel = uilabel(app.AddOptiontoPortfolioPanel);
            app.UnderlyingDropDownLabel.Text = 'Underlying';
            app.UnderlyingDropDown = uidropdown(app.AddOptiontoPortfolioPanel);
            app.UnderlyingDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.UnderlyingDropDown.ValueChangedFcn = createCallbackFcn(app, @UnderlyingDropDownValueChanged, true);
            app.UnderlyingDropDown.Value = 'USD/AUD';
            app.StorePortfolioButton = uibutton(app.AddOptiontoPortfolioPanel, 'push');
            app.StorePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @StorePortfolioButtonPushed, true);
            app.StorePortfolioButton.Enable = 'off';
            app.StorePortfolioButton.Text = 'Store Portfolio';
            app.PortfolioCompositionPanel = uipanel(app.PortfolioSelectionTab);
            app.PortfolioCompositionPanel.Title = 'Portfolio Composition';
            app.UIAxes5 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.PortfolioCompositionPanel);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable3 = uitable(app.PortfolioSelectionTab);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = [true true true true true true true true true true true true];
            app.UITable4 = uitable(app.PortfolioSelectionTab);
            app.UITable4.ColumnName = '';
            app.UITable4.RowName = {};
            app.UITable2 = uitable(app.PortfolioSelectionTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = [true true true true true true true true true true true];
            app.LONGLabel = uilabel(app.PortfolioSelectionTab);
            app.LONGLabel.Text = 'LONG';
            app.SHORTLabel = uilabel(app.PortfolioSelectionTab);
            app.SHORTLabel.Text = 'SHORT';
            app.TOTLabel = uilabel(app.PortfolioSelectionTab);
            app.TOTLabel.Text = 'TOT';
            app.TimeSeriesAnalysisTab = uitab(app.TabGroup);
            app.TimeSeriesAnalysisTab.Title = 'Time Series Analysis';
            app.TimeSeriesChartPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartPanel.Title = 'Time Series Chart';
            app.UIAxes6 = uiaxes(app.TimeSeriesChartPanel);
            xlabel(app.UIAxes6, 'Date')
            ylabel(app.UIAxes6, 'Exchange Rate')
            zlabel(app.UIAxes6, 'Z')
            app.TimeSeriesChartControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.TimeSeriesChartControlPanel.Title = 'Time Series Chart Control Panel';
            app.StartDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Value = datetime([1995 3 2]);
            app.EndDateDatePickerLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TimeSeriesChartControlPanel);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Value = datetime([2021 3 4]);
            app.Series1DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series1DropDownLabel.Text = 'Series 1';
            app.Series1DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series1DropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series1DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.Series1DropDown.ValueChangedFcn = createCallbackFcn(app, @Series1DropDownValueChanged, true);
            app.Series1DropDown.Value = '1';
            app.Series2DropDownLabel = uilabel(app.TimeSeriesChartControlPanel);
            app.Series2DropDownLabel.Text = 'Series 2';
            app.Series2DropDown = uidropdown(app.TimeSeriesChartControlPanel);
            app.Series2DropDown.Items = {'No plot', 'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.Series2DropDown.ValueChangedFcn = createCallbackFcn(app, @Series2DropDownValueChanged, true);
            app.Series2DropDown.Value = 'EUR/USD';
            app.LogReturnsDistributionFitterPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.LogReturnsDistributionFitterPanel.Title = 'Log-Returns Distribution Fitter';
            app.CurrencyDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDownLabel.Text = 'Currency';
            app.CurrencyDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.CurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.CurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.CurrencyDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrencyDropDownValueChanged, true);
            app.CurrencyDropDown.Value = 1;
            app.StartDateDatePicker_3Label = uilabel(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3Label.Text = 'Start Date';
            app.StartDateDatePicker_3 = uidatepicker(app.LogReturnsDistributionFitterPanel);
            app.StartDateDatePicker_3.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_3ValueChanged, true);
            app.OverlayDistributionCheckBox = uicheckbox(app.LogReturnsDistributionFitterPanel);
            app.OverlayDistributionCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayDistributionCheckBoxValueChanged, true);
            app.OverlayDistributionCheckBox.Text = 'Overlay Distribution';
            app.DistributionTypeDropDownLabel = uilabel(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDownLabel.Text = 'Distribution Type';
            app.DistributionTypeDropDown = uidropdown(app.LogReturnsDistributionFitterPanel);
            app.DistributionTypeDropDown.Items = {'Normal', 'Student-t'};
            app.DistributionTypeDropDown.ItemsData = [1 2];
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Enable = 'off';
            app.DistributionTypeDropDown.Value = 1;
            app.TextArea = uitextarea(app.LogReturnsDistributionFitterPanel);
            app.UIAxes7 = uiaxes(app.LogReturnsDistributionFitterPanel);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UITable5 = uitable(app.TimeSeriesAnalysisTab);
            app.UITable5.ColumnName = '';
            app.UITable5.RowName = {};
            app.UITable5.ColumnSortable = [true true true true true true true true true true true true true true true true];
            app.CorrelationMapPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapPanel.Title = 'Correlation Map';
            app.CorrelationMapandSummaryStatisticsControlPanel = uipanel(app.TimeSeriesAnalysisTab);
            app.CorrelationMapandSummaryStatisticsControlPanel.Title = 'Correlation Map and Summary Statistics Control Panel';
            app.StartDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.StartDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.StartDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_2ValueChanged, true);
            app.StartDateDatePicker_2.Value = datetime([1995 3 2]);
            app.EndDateDatePicker_2Label = uilabel(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.EndDateDatePicker_2 = uidatepicker(app.CorrelationMapandSummaryStatisticsControlPanel);
            app.EndDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePicker_2ValueChanged, true);
            app.EndDateDatePicker_2.Value = datetime([2021 3 4]);
            app.BackgroundColorButton = uibutton(app.CorrelationMapandSummaryStatisticsControlPanel, 'state');
            app.BackgroundColorButton.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorButtonValueChanged, true);
            app.BackgroundColorButton.Text = 'Background Color';
            app.BackgroundColorButton.Value = true;
            app.VaRCalculationandBacktestingTab = uitab(app.TabGroup);
            app.VaRCalculationandBacktestingTab.Title = 'VaR Calculation and Backtesting';
            app.UITable6 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable6.ColumnName = '';
            app.UITable6.RowName = {};
            app.StepladderCurrencyDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.StepladderCurrencyDropDownLabel.Text = 'Stepladder Currency';
            app.StepladderCurrencyDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.StepladderCurrencyDropDown.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.StepladderCurrencyDropDown.ItemsData = [1 2 3 4 5 6];
            app.StepladderCurrencyDropDown.Value = 1;
            app.Button = uibutton(app.VaRCalculationandBacktestingTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SamplingPeriodDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.SamplingPeriodDropDownLabel.Text = 'Sampling Period';
            app.SamplingPeriodDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.SamplingPeriodDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingPeriodDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingPeriodDropDown.Value = 6;
            app.RiskHorizonEditFieldLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.RiskHorizonEditFieldLabel.Text = 'Risk Horizon';
            app.RiskHorizonEditField = uieditfield(app.VaRCalculationandBacktestingTab, 'numeric');
            app.RiskHorizonEditField.Value = 1;
            app.TextArea_2Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.VaRCalculationandBacktestingTab);
            app.UITable7 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable7.ColumnName = '';
            app.UITable7.RowName = {};
            app.UITable8 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable8.ColumnName = '';
            app.UITable8.RowName = {};
            app.VaRandESMethodDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRandESMethodDropDownLabel.Text = 'VaR and ES Method';
            app.VaRandESMethodDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.VaRandESMethodDropDown.Items = {'Normal Distribution', 'Student''s t Distribution'};
            app.VaRandESMethodDropDown.ItemsData = [1 2];
            app.VaRandESMethodDropDown.Value = 1;
            app.VaRBacktestingHorizonDatePickerLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRBacktestingHorizonDatePickerLabel.Text = 'VaR Backtesting Horizon';
            app.VaRBacktestingHorizonDatePicker = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.VaRBacktestingHorizonDatePicker.Value = datetime([2020 3 11]);
            app.VaRLimitMlnEditFieldLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.VaRLimitMlnEditFieldLabel.Text = 'VaR Limit (Mln)';
            app.VaRLimitMlnEditField = uieditfield(app.VaRCalculationandBacktestingTab, 'numeric');
            app.SamplingRollingWindowDropDownLabel = uilabel(app.VaRCalculationandBacktestingTab);
            app.SamplingRollingWindowDropDownLabel.Text = 'Sampling Rolling Window';
            app.SamplingRollingWindowDropDown = uidropdown(app.VaRCalculationandBacktestingTab);
            app.SamplingRollingWindowDropDown.Items = {'6 Months', '9 Months', '1 Year', '2 Years', '3 Years', '5 Years'};
            app.SamplingRollingWindowDropDown.ItemsData = [6 9 12 24 36 60];
            app.SamplingRollingWindowDropDown.Value = 6;
            app.StartDateDatePicker_4Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.StartDateDatePicker_4Label.Text = 'Start Date';
            app.StartDateDatePicker_4 = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.EndDateDatePicker_3Label = uilabel(app.VaRCalculationandBacktestingTab);
            app.EndDateDatePicker_3Label.Text = 'End Date';
            app.EndDateDatePicker_3 = uidatepicker(app.VaRCalculationandBacktestingTab);
            app.Button2 = uibutton(app.VaRCalculationandBacktestingTab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.UITable10 = uitable(app.VaRCalculationandBacktestingTab);
            app.UITable10.ColumnName = '';
            app.UITable10.RowName = {};
            app.UIAxes8 = uiaxes(app.VaRCalculationandBacktestingTab);
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.VaRCalculationandBacktestingTab);
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.DeskPerformanceTab = uitab(app.TabGroup);
            app.DeskPerformanceTab.Title = 'Desk Performance';
            app.ImportOptionsDataButton = uibutton(app.DeskPerformanceTab, 'push');
            app.ImportOptionsDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportOptionsDataButtonPushed, true);
            app.ImportOptionsDataButton.Text = 'Import Options Data';
            app.GenerateChartButton = uibutton(app.DeskPerformanceTab, 'push');
            app.GenerateChartButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateChartButtonPushed, true);
            app.GenerateChartButton.Text = 'Generate Chart';
            app.UITable11 = uitable(app.DeskPerformanceTab);
            app.UITable11.ColumnName = '';
            app.UITable11.RowName = {};
            app.UITable12 = uitable(app.DeskPerformanceTab);
            app.UITable12.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable12.RowName = {};
            app.UpdateDataButton = uibutton(app.DeskPerformanceTab, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.USDAUDPORTFOLIOLabel = uilabel(app.DeskPerformanceTab);
            app.USDAUDPORTFOLIOLabel.Text = 'USD/AUD PORTFOLIO';
            app.UnderlyingDropDown_2Label = uilabel(app.DeskPerformanceTab);
            app.UnderlyingDropDown_2Label.Text = 'Underlying';
            app.UnderlyingDropDown_2 = uidropdown(app.DeskPerformanceTab);
            app.UnderlyingDropDown_2.Items = {'USD/AUD', 'EUR/USD', 'USD/GBP', 'AUD/GBP', 'EUR/GBP', 'AUD/EUR'};
            app.UnderlyingDropDown_2.ItemsData = [1 2 3 4 5 6];
            app.UnderlyingDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UnderlyingDropDown_2ValueChanged, true);
            app.UnderlyingDropDown_2.Value = 1;
            app.UIAxes10 = uiaxes(app.DeskPerformanceTab);
            xlabel(app.UIAxes10, 'Date')
            ylabel(app.UIAxes10, 'VaR')
            zlabel(app.UIAxes10, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end