classdef RateandSettleDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AddRatetoCurveButton     matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        RatedateDatePickerLabel  matlab.ui.control.Label
        RatedateDatePicker       matlab.ui.control.DatePicker
        RateEditFieldLabel       matlab.ui.control.Label
        RateEditField            matlab.ui.control.NumericEditField
        SettleDatePickerLabel    matlab.ui.control.Label
        SettleDatePicker         matlab.ui.control.DatePicker
        StoreDataButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        Settle
        Dates
        Rates
        RateCurve
        counter
        Test
    end
    
    methods (Access = public)
        
        function [Settle, ZeroCurve] = ZeroCurveCalc(app,Settle,Rates,Dates)
            Settle = Settle;
            ZeroCurve = ratecurve("zero",Settle,Dates,Rates)
            
        end
        
        function UpdatePlot(app)
            nx = app.Settle:days(2):app.Dates(end);
            IntPoints = interp1([app.Settle app.Dates],[0 app.Rates],nx,"spline");
            plot(app.UIAxes,[app.Settle app.Dates],[0 app.Rates],nx,IntPoints)
            ylim(app.UIAxes,[0 max(app.Rates)+0.02])
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: AddRatetoCurveButton
        function AddRatetoCurveButtonPushed(app, event)
            app.Settle = app.SettleDatePicker.Value;
            app.counter = app.counter + 1;
            app.Rates = [app.Rates app.RateEditField.Value];
            app.Dates = [app.Dates app.RatedateDatePicker.Value];


            app.Test(1) = 0;
            if app.counter == 1
            else 
                for i = 1:app.counter-1
                    if app.Dates(i) == app.Dates(app.counter)
                        app.Test(i) = 1;
                    else
                        app.Test(i) = 0;
                    end
                end
            end
            
            if nnz(app.Test)>0
                
            end


            app.UITable.Data = table(app.Dates',app.Rates','VariableNames',{'Date' 'Rate'});
            UpdatePlot(app)
            
        end

        % Button pushed function: StoreDataButton
        function StoreDataButtonPushed(app, event)
        [app.CallingApp.Settle app.CallingApp.ZeroCurve] = ZeroCurveCalc(app,app.SettleDatePicker.Value,app.Rates,app.Dates); 
            app.CallingApp.SetDateandRatesButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AddRatetoCurveButton = uibutton(app.UIFigure, 'push');
            app.AddRatetoCurveButton.ButtonPushedFcn = createCallbackFcn(app, @AddRatetoCurveButtonPushed, true);
            app.AddRatetoCurveButton.Text = 'Add Rate to Curve';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Rate'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true true true false];
            app.RatedateDatePickerLabel = uilabel(app.UIFigure);
            app.RatedateDatePickerLabel.Text = 'Rate date';
            app.RatedateDatePicker = uidatepicker(app.UIFigure);
            app.RatedateDatePicker.Value = datetime([2021 4 2]);
            app.RateEditFieldLabel = uilabel(app.UIFigure);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateEditField.Value = 0.05;
            app.SettleDatePickerLabel = uilabel(app.UIFigure);
            app.SettleDatePickerLabel.Text = 'Settle';
            app.SettleDatePicker = uidatepicker(app.UIFigure);
            app.SettleDatePicker.Value = datetime([2021 3 2]);
            app.StoreDataButton = uibutton(app.UIFigure, 'push');
            app.StoreDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreDataButtonPushed, true);
            app.StoreDataButton.Text = 'Store Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zero Curve')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Rate')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RateandSettleDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end